classdef index < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        imageprocessingUIFigure     matlab.ui.Figure
        Panel_2                     matlab.ui.container.Panel
        ColResize                   matlab.ui.control.NumericEditField
        colLabel                    matlab.ui.control.Label
        RowResize                   matlab.ui.control.NumericEditField
        rowLabel                    matlab.ui.control.Label
        ButtonReverse               matlab.ui.control.Button
        Button90                    matlab.ui.control.Button
        blurButton                  matlab.ui.control.Button
        sharpenButton               matlab.ui.control.Button
        edgedetectionDropDown       matlab.ui.control.DropDown
        edgedetectionDropDownLabel  matlab.ui.control.Label
        featureDropDown             matlab.ui.control.DropDown
        featureDropDownLabel        matlab.ui.control.Label
        addnoiseDropDown            matlab.ui.control.DropDown
        addnoiseLabel               matlab.ui.control.Label
        saveButton                  matlab.ui.control.Button
        resetButton                 matlab.ui.control.Button
        resizeButton                matlab.ui.control.Button
        ImageprocessingLabel        matlab.ui.control.Label
        graySwitch                  matlab.ui.control.Switch
        graySwitchLabel             matlab.ui.control.Label
        brightnessSlider            matlab.ui.control.Slider
        brightnessLabel             matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        UploadImage                 matlab.ui.control.Button
        AddressimageEditField       matlab.ui.control.EditField
        addressimageLabel           matlab.ui.control.Label
        UploadimageLabel            matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        OutputimageTab              matlab.ui.container.Tab
        HeightOutValue              matlab.ui.control.Label
        HeightOutLabel              matlab.ui.control.Label
        WidthOutValue               matlab.ui.control.Label
        WidthOutLabel               matlab.ui.control.Label
        Outputimage                 matlab.ui.control.Image
        OriginalimageTab            matlab.ui.container.Tab
        HeightOriginalValue         matlab.ui.control.Label
        HeightOriginalLabel         matlab.ui.control.Label
        WidthOriginalValue          matlab.ui.control.Label
        WidthOriginalLabel          matlab.ui.control.Label
        Originalimage               matlab.ui.control.Image
    end

    
    properties (Access = public)
        SelectedImage
        SelectedImageOriginal
        beforegray
        beforegray2
        berforeEdges
    end
    
    methods (Access = public)
        
        function enable_disabel_all(app,status)
            app.RowResize.Enable =status;
            app.RowResize.Editable =status;
            app.ColResize.Enable =status;
            app.ColResize.Editable =status;
            app.resizeButton.Enable = status ;
            app.brightnessSlider.Enable = status;
            app.brightnessLabel.Enable = status;
            app.graySwitch.Enable = status;
            app.graySwitchLabel.Enable= status;
            app.addnoiseDropDown.Enable = status;
            app.addnoiseDropDown.Editable = status;
            app.featureDropDown.Enable = status;
            app.featureDropDown.Editable = status;
            app.edgedetectionDropDown.Enable = status;
            app.edgedetectionDropDown.Editable = status;
            app.addnoiseLabel.Enable = status;
            app.sharpenButton.Enable = status;
            app.blurButton.Enable = status;
            app.Button90.Enable = status;
            app.ButtonReverse.Enable = status;
            app.Outputimage.Enable = status;
            app.Originalimage.Enable = status;
        end
        
        function btn_reset_save (app,status)
            app.resetButton.Enable = status;
            app.saveButton.Enable = status;
        end
        
        function result = gray_image(~,img)
            grayImg = rgb2gray(img);
            GrayImg3 = cat(3, grayImg, grayImg, grayImg);
            result = uint8(GrayImg3);
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: UploadImage
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, 'Uplaod image');
            if isequal(file, 0)
                return;
            end
            app.AddressimageEditField.Value = path ;
            image = imread(fullfile(path,file));
            [imageHeight, imageWidth, ~] = size(image);
            app.SelectedImage = image ;
            app.SelectedImageOriginal = image;
            app.ColResize.Limits = [1,imageHeight];
            app.ColResize.Value = imageHeight / 2;
            app.RowResize.Limits = [1,imageWidth];
            app.RowResize.Value = imageWidth / 2;
            app.WidthOriginalValue.Text = num2str(imageWidth);
            app.HeightOriginalValue.Text = num2str(imageHeight);
            app.WidthOutValue.Text = num2str(imageWidth);
            app.HeightOutValue.Text = num2str(imageHeight);
            app.Originalimage.ImageSource = image ;
            app.Outputimage.ImageSource = image ;
            enable_disabel_all(app,"on")
        end

        % Button pushed function: resizeButton
        function resizeButtonPushed(app, event)
            row_size =  app.RowResize.Value ;
            col_size =  app.ColResize.Value ;
            if col_size <= 0 || row_size <= 0 
                errordlg('Should not be 0', 'Error');
                return;
            end
            resized_img = imresize(app.Outputimage.ImageSource, [row_size,col_size]);
            app.SelectedImage = resized_img;
            [imageHeight, imageWidth, ~] = size(resized_img);
            app.WidthOutValue.Text = num2str(imageWidth);
            app.HeightOutValue.Text = num2str(imageHeight);
            app.Outputimage.ImageSource = resized_img;
            btn_reset_save(app,"on");
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            btn_reset_save(app,"off")
            choice = uiconfirm(app.imageprocessingUIFigure, 'Do you want to reset image?','Confirmation','Options', {'Yes', 'Cancel'},'DefaultOption', 2);
            if strcmp(choice,'Yes')
                image_originial = app.SelectedImageOriginal;
                app.SelectedImage = image_originial;
                app.Outputimage.ImageSource = image_originial;
                [imageHeight, imageWidth, ~] = size(image_originial);
                app.WidthOutValue.Text = num2str(imageWidth);
                app.HeightOutValue.Text = num2str(imageHeight);
                app.brightnessSlider.Value = 0;
                app.addnoiseDropDown.Value = '------';
                app.featureDropDown.Value = '------';
                app.edgedetectionDropDown.Value = '------';
                app.graySwitch.Value = 'off';
            else
                return;
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            filter = {'*.jpg'; '*.png'; '*.tif'};
            prompt = {'name image:'};
            defaultAnswer = {'mahdi.jafari'};
            dlgTitle = 'Input';
            numLines = 1;
            userInputs = inputdlg(prompt, dlgTitle, numLines, defaultAnswer);
            if isempty(userInputs)
                return;
            end
            value = userInputs{1} ;
            [filename, filepath] = uiputfile(filter,'export_image',value);
            if ischar(filename)
                imwrite(app.Outputimage.ImageSource,[filepath, filename]);
            end
        end

        % Value changing function: brightnessSlider
        function brightnessSliderValueChanging(app, event)
            changingValue = event.Value;
            btn_reset_save(app,"on");
            brightenedImage = uint8(double(app.SelectedImage) * changingValue);
            app.Outputimage.ImageSource = brightenedImage;
        end

        % Value changed function: graySwitch
        function graySwitchValueChanged(app, event)
            value = app.graySwitch.Value;
            btn_reset_save(app,"on");
            if strcmp(value,'on')
                out_image = app.Outputimage.ImageSource;
                app.beforegray = out_image;
                app.beforegray2 = app.SelectedImage;
                if ndims(out_image) ~= 3 || size(out_image, 3) ~= 3
                    errordlg('The image is not in RGB format.', 'Error');
                else
                    app.Outputimage.ImageSource = gray_image(app,out_image);
                    app.SelectedImage = gray_image(app,out_image);
                end
            else
                app.Outputimage.ImageSource = app.beforegray;
                app.SelectedImage = app.beforegray2;
            end
            
        end

        % Value changed function: addnoiseDropDown
        function addnoiseDropDownValueChanged(app, event)
            value = app.addnoiseDropDown.Value;
            btn_reset_save(app,"on");
            if strcmp(value,'salt & pepper')
                prompt = {'d:'};
                defaultAnswer = {'0.2'};
            elseif strcmp(value,'gaussian')
                prompt = {'M:', 'var_gauss:'};
                defaultAnswer = {'0.3', '0.3'};
            else
                return;
            end
            dlgTitle = 'Input';
            numLines = 1;
            userInputs = inputdlg(prompt, dlgTitle, numLines, defaultAnswer);
            if isempty(userInputs)
                return;
            end
            value1 = str2double(userInputs{1});
            im = app.Outputimage.ImageSource;
            if strcmp(value,'salt & pepper')
                if value1 >= 1 
                    errordlg('must be less than 1', 'Error');
                    return;
                end
                img=imnoise(im,'salt & pepper',value1);
            else
                value2 = str2double(userInputs{2});
                if value1 >= 1 || value2 >= 1
                    errordlg('must be less than 1', 'Error');
                    return;
                end

                img=imnoise(im,'gaussian',value1,value2);
            end
            app.Outputimage.ImageSource = img;
            app.SelectedImage = img;
        end

        % Button pushed function: sharpenButton
        function sharpenButtonPushed(app, event)
            btn_reset_save(app,"on");
            prompt = {'value:'};
            defaultAnswer = {'15'};
            dlgTitle = 'Input';
            numLines = 1;
            userInputs = inputdlg(prompt, dlgTitle, numLines, defaultAnswer);
            if isempty(userInputs)
                return;
            end
            value = str2double(userInputs{1});
            if value <= 0 
                errordlg('value should not be 0)', 'Error');
                return;
            end
            sharpened_image = imsharpen(app.Outputimage.ImageSource,"Radius",value);
            app.Outputimage.ImageSource = sharpened_image;
            app.SelectedImage = sharpened_image;
        end

        % Button pushed function: blurButton
        function blurButtonPushed(app, event)
            btn_reset_save(app,"on");
            prompt = {'size filter x:', 'size filter y:','sigma:'};
            defaultAnswer = {'15', '15','15'};
            dlgTitle = 'Input';
            numLines = 1;
            userInputs = inputdlg(prompt, dlgTitle, numLines, defaultAnswer);
            if isempty(userInputs)
                return;
            end
            value1 = str2double(userInputs{1});
            value2 = str2double(userInputs{2});
            value3 = str2double(userInputs{3});
            if (1>= value1 || value1 >=100) ||  (1>= value2 || value2 >=100)
                errordlg('The larger one is equal to 1 and the smaller one is equal to 100 (size filter x,size filter y)', 'Error');
                return;
            end
            if 1>= value1 || value3 >=30 
                errordlg('The larger one is equal to 1 and the smaller one is equal to 30 (sigma)', 'Error');
                return;
            end
            gaussian_filter = fspecial('gaussian', [value1, value2],value3);
            blurred_image = imfilter(app.Outputimage.ImageSource, gaussian_filter, 'replicate');
            app.Outputimage.ImageSource = blurred_image;
            app.SelectedImage = blurred_image;
        end

        % Button pushed function: Button90
        function Button90Pushed(app, event)
            btn_reset_save(app,"on");
            rotated_img = imrotate(app.Outputimage.ImageSource, 90);
            app.Outputimage.ImageSource = rotated_img;
            app.SelectedImage = rotated_img;
        end

        % Button pushed function: ButtonReverse
        function ButtonReversePushed(app, event)
            btn_reset_save(app,"on");
            rotated_img = imrotate(app.Outputimage.ImageSource, 180);
            app.Outputimage.ImageSource = rotated_img;
            app.SelectedImage = rotated_img;
        end

        % Value changed function: featureDropDown
        function featureDropDownValueChanged(app, event)
            value = app.featureDropDown.Value;
            btn_reset_save(app,"on");
            if strcmp(value,'Harris') || strcmp(value,'SIFT')
                prompt = {'sigma:'};
                defaultAnswer = {'2'};
                dlgTitle = 'Input';
                userInputs = inputdlg(prompt, dlgTitle, 1, defaultAnswer);
                if isempty(userInputs)
                    return;
                end
                value_input = str2double(userInputs{1});
                if value_input<=0 || value_input >=10 
                    errordlg('sigma should not be 0 and the smaller one is equal to 10', 'Error');
                    return;
                end
                image_gray = rgb2gray(app.Outputimage.ImageSource);
                if strcmp(value,'Harris')
                    harrisPoints = detectHarrisFeatures(image_gray*value_input);
                    corners = insertMarker(app.Outputimage.ImageSource, harrisPoints.Location, 'x', 'Color', 'g','size',5);
                    app.Outputimage.ImageSource = corners;
                    app.SelectedImage = corners;
                elseif strcmp(value,'SIFT')
                    im_sc = imresize(image_gray, 1/value_input);
                    siftPoints = detectSIFTFeatures(im_sc);
                    figure('Visible','off'); 
                    imshow(app.Outputimage.ImageSource); 
                    hold on;
                    plot(siftPoints);
                    frame = getframe(gca);
                    combinedImage = frame2im(frame);
                    app.Outputimage.ImageSource = combinedImage;
                    app.SelectedImage = combinedImage;
                else
                    return;
                end
            else
                return
            end

        end

        % Image clicked function: Outputimage
        function OutputimageImageClicked(app, event)
            figure;
            imshow(app.Outputimage.ImageSource);
            title('Output Image');
        end

        % Image clicked function: Originalimage
        function OriginalimageImageClicked(app, event)
            figure;
            imshow(app.Originalimage.ImageSource);
            imshow(app.O)
            title('Original Image');
        end

        % Value changed function: edgedetectionDropDown
        function edgedetectionDropDownValueChanged(app, event)
            value = app.edgedetectionDropDown.Value;
            btn_reset_save(app,"on");
            if isempty(app.berforeEdges)
                app.berforeEdges = app.SelectedImage;
            end
            grayImage = rgb2gray(app.berforeEdges);
            if strcmp(value,'Sobel')
                prompt = {'thresh:'};
                defaultAnswer = {'0.1'};
                dlgTitle = 'Input';
                userInputs = inputdlg(prompt, dlgTitle, 1, defaultAnswer);
                if isempty(userInputs)
                    return;
                end
                thresh = str2double(userInputs{1});
                if thresh <= 0 
                    errordlg('thresh should not be 0)', 'Error');
                    return;
                end
                edges = edge(grayImage, 'Sobel',thresh);
            elseif strcmp(value,'Canny')
                prompt = {'thresh:','sigma:'};
                defaultAnswer = {'0.1','0.1'};
                dlgTitle = 'Input';
                userInputs = inputdlg(prompt, dlgTitle, 1, defaultAnswer);
                if isempty(userInputs)
                    return;
                end
                thresh = str2double(userInputs{1});
                
                if thresh <= 0 || thresh >=1
                    errordlg('thresh must be between 0 and 1', 'Error');
                    return;
                end
                sigma = str2double(userInputs{2});
                if sigma <= 0 
                    errordlg('sigma should not be 0', 'Error');
                    return;
                end
                edges = edge(grayImage, 'Canny',thresh,sigma);                       
            else
                return;
            end
            edgesRGB = cat(3, edges, edges, edges);
            edgesRGB = uint8(edgesRGB * 255);
            app.Outputimage.ImageSource = edgesRGB;
            app.SelectedImage = edgesRGB;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.imageprocessingUIFigure = uifigure('Visible', 'off');
            app.imageprocessingUIFigure.Name = 'image processing';
            app.TabGroup = uitabgroup(app.imageprocessingUIFigure);
            app.OutputimageTab = uitab(app.TabGroup);
            app.OutputimageTab.Title = 'Output image';
            app.Outputimage = uiimage(app.OutputimageTab);
            app.Outputimage.ImageClickedFcn = createCallbackFcn(app, @OutputimageImageClicked, true);
            app.Outputimage.Enable = 'off';
            app.WidthOutLabel = uilabel(app.OutputimageTab);
            app.WidthOutLabel.Text = 'Width : ';
            app.WidthOutValue = uilabel(app.OutputimageTab);
            app.WidthOutValue.Text = '0';
            app.HeightOutLabel = uilabel(app.OutputimageTab);
            app.HeightOutLabel.Text = 'Height : ';
            app.HeightOutValue = uilabel(app.OutputimageTab);
            app.HeightOutValue.Text = '0';
            app.OriginalimageTab = uitab(app.TabGroup);
            app.OriginalimageTab.Title = 'Original image';
            app.Originalimage = uiimage(app.OriginalimageTab);
            app.Originalimage.ImageClickedFcn = createCallbackFcn(app, @OriginalimageImageClicked, true);
            app.Originalimage.Enable = 'off';
            app.WidthOriginalLabel = uilabel(app.OriginalimageTab);
            app.WidthOriginalLabel.Text = 'Width : ';
            app.WidthOriginalValue = uilabel(app.OriginalimageTab);
            app.WidthOriginalValue.Text = '0';
            app.HeightOriginalLabel = uilabel(app.OriginalimageTab);
            app.HeightOriginalLabel.Text = 'Height : ';
            app.HeightOriginalValue = uilabel(app.OriginalimageTab);
            app.HeightOriginalValue.Text = '0';
            app.Panel = uipanel(app.imageprocessingUIFigure);
            app.UploadimageLabel = uilabel(app.Panel);
            app.UploadimageLabel.Text = 'Upload image';
            app.addressimageLabel = uilabel(app.Panel);
            app.addressimageLabel.Text = 'Address image :';
            app.AddressimageEditField = uieditfield(app.Panel, 'text');
            app.UploadImage = uibutton(app.Panel, 'push');
            app.UploadImage.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImage.Icon = fullfile(pathToMLAPP, 'icone', 'upload.png');
            app.UploadImage.Text = '';
            app.Panel_2 = uipanel(app.imageprocessingUIFigure);
            app.brightnessLabel = uilabel(app.Panel_2);
            app.brightnessLabel.Text = 'brightness : ';
            app.brightnessSlider = uislider(app.Panel_2);
            app.brightnessSlider.Limits = [0 10];
            app.brightnessSlider.ValueChangingFcn = createCallbackFcn(app, @brightnessSliderValueChanging, true);
            app.brightnessSlider.Enable = 'off';
            app.graySwitchLabel = uilabel(app.Panel_2);
            app.graySwitchLabel.Enable = 'off';
            app.graySwitchLabel.Text = 'gray :';
            app.graySwitch = uiswitch(app.Panel_2, 'slider');
            app.graySwitch.Items = {'off', 'on'};
            app.graySwitch.ValueChangedFcn = createCallbackFcn(app, @graySwitchValueChanged, true);
            app.graySwitch.Enable = 'off';
            app.graySwitch.Value = 'off';
            app.ImageprocessingLabel = uilabel(app.Panel_2);
            app.ImageprocessingLabel.Text = 'Image processing';
            app.resizeButton = uibutton(app.Panel_2, 'push');
            app.resizeButton.ButtonPushedFcn = createCallbackFcn(app, @resizeButtonPushed, true);
            app.resizeButton.Enable = 'off';
            app.resizeButton.Text = 'resize';
            app.resetButton = uibutton(app.Panel_2, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Icon = 'slrtRebootIcon.png';
            app.resetButton.Enable = 'off';
            app.resetButton.Text = 'reset';
            app.saveButton = uibutton(app.Panel_2, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Icon = 'saveResults_24.png';
            app.saveButton.Enable = 'off';
            app.saveButton.Text = 'save';
            app.addnoiseLabel = uilabel(app.Panel_2);
            app.addnoiseLabel.Text = 'add noise :';
            app.addnoiseDropDown = uidropdown(app.Panel_2);
            app.addnoiseDropDown.Items = {'------', 'salt & pepper', 'gaussian'};
            app.addnoiseDropDown.ValueChangedFcn = createCallbackFcn(app, @addnoiseDropDownValueChanged, true);
            app.addnoiseDropDown.Enable = 'off';
            app.addnoiseDropDown.Value = '------';
            app.featureDropDownLabel = uilabel(app.Panel_2);
            app.featureDropDownLabel.Text = 'feature :';
            app.featureDropDown = uidropdown(app.Panel_2);
            app.featureDropDown.Items = {'------', 'Harris', 'SIFT'};
            app.featureDropDown.ValueChangedFcn = createCallbackFcn(app, @featureDropDownValueChanged, true);
            app.featureDropDown.Enable = 'off';
            app.featureDropDown.Value = '------';
            app.edgedetectionDropDownLabel = uilabel(app.Panel_2);
            app.edgedetectionDropDownLabel.Text = 'edge detection :';
            app.edgedetectionDropDown = uidropdown(app.Panel_2);
            app.edgedetectionDropDown.Items = {'------', 'Sobel', 'Canny'};
            app.edgedetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @edgedetectionDropDownValueChanged, true);
            app.edgedetectionDropDown.Enable = 'off';
            app.edgedetectionDropDown.Value = '------';
            app.sharpenButton = uibutton(app.Panel_2, 'push');
            app.sharpenButton.ButtonPushedFcn = createCallbackFcn(app, @sharpenButtonPushed, true);
            app.sharpenButton.Enable = 'off';
            app.sharpenButton.Text = 'sharpen';
            app.blurButton = uibutton(app.Panel_2, 'push');
            app.blurButton.ButtonPushedFcn = createCallbackFcn(app, @blurButtonPushed, true);
            app.blurButton.Enable = 'off';
            app.blurButton.Text = 'blur';
            app.Button90 = uibutton(app.Panel_2, 'push');
            app.Button90.ButtonPushedFcn = createCallbackFcn(app, @Button90Pushed, true);
            app.Button90.Icon = fullfile(pathToMLAPP, 'icone', '90-degree-rotate-icon.svg');
            app.Button90.Enable = 'off';
            app.Button90.Text = '';
            app.ButtonReverse = uibutton(app.Panel_2, 'push');
            app.ButtonReverse.ButtonPushedFcn = createCallbackFcn(app, @ButtonReversePushed, true);
            app.ButtonReverse.Icon = fullfile(pathToMLAPP, 'icone', 'reverse.svg');
            app.ButtonReverse.Enable = 'off';
            app.ButtonReverse.Text = '';
            app.rowLabel = uilabel(app.Panel_2);
            app.rowLabel.Text = 'row:';
            app.RowResize = uieditfield(app.Panel_2, 'numeric');
            app.RowResize.Limits = [10 100];
            app.RowResize.Editable = 'off';
            app.RowResize.Enable = 'off';
            app.RowResize.Value = 10;
            app.colLabel = uilabel(app.Panel_2);
            app.colLabel.Text = 'col:';
            app.ColResize = uieditfield(app.Panel_2, 'numeric');
            app.ColResize.Limits = [1 10];
            app.ColResize.Editable = 'off';
            app.ColResize.Enable = 'off';
            app.ColResize.Value = 2;
            % Show the figure after all components are created
            app.imageprocessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = index

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.imageprocessingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.imageprocessingUIFigure)
        end
    end
end