classdef index < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        imageprocessingUIFigure  matlab.ui.Figure
        Panel_2                  matlab.ui.container.Panel
        addnoiseDropDown         matlab.ui.control.DropDown
        addnoiseLabel            matlab.ui.control.Label
        saveButton               matlab.ui.control.Button
        resetButton              matlab.ui.control.Button
        resizeButton             matlab.ui.control.Button
        ImageprocessingLabel     matlab.ui.control.Label
        ResizeLabel              matlab.ui.control.Label
        yResize                  matlab.ui.control.NumericEditField
        WidthLabel_2             matlab.ui.control.Label
        xResize                  matlab.ui.control.NumericEditField
        WidthLabel               matlab.ui.control.Label
        Switch_2                 matlab.ui.control.Switch
        Switch_2Label            matlab.ui.control.Label
        graySwitch               matlab.ui.control.Switch
        graySwitchLabel          matlab.ui.control.Label
        brightnessSlider         matlab.ui.control.Slider
        brightnessLabel          matlab.ui.control.Label
        Panel                    matlab.ui.container.Panel
        UploadImage              matlab.ui.control.Button
        AddressimageEditField    matlab.ui.control.EditField
        addressimageLabel        matlab.ui.control.Label
        UploadimageLabel         matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        OutputimageTab           matlab.ui.container.Tab
        HeightOutValue           matlab.ui.control.Label
        HeightOutLabel           matlab.ui.control.Label
        WidthOutValue            matlab.ui.control.Label
        WidthOutLabel            matlab.ui.control.Label
        Outputimage              matlab.ui.control.Image
        OriginalimageTab         matlab.ui.container.Tab
        HeightOriginalValue      matlab.ui.control.Label
        HeightOriginalLabel      matlab.ui.control.Label
        WidthOriginalValue       matlab.ui.control.Label
        WidthOriginalLabel       matlab.ui.control.Label
        Originalimage            matlab.ui.control.Image
    end

    
    properties (Access = public)
        SelectedImage
        SelectedImageOriginal
        beforegray
        beforegray2
    end
    
    methods (Access = public)
        
        function enable_disabel_all(app,status)
            app.xResize.Enable =status;
            app.xResize.Editable =status;
            app.yResize.Enable =status;
            app.yResize.Editable =status;
            app.resizeButton.Enable = status ;
            app.brightnessSlider.Enable = status;
            app.brightnessLabel.Enable = status;
            app.graySwitch.Enable = status;
            app.graySwitchLabel.Enable= status;
            app.addnoiseDropDown.Enable = status;
            app.addnoiseDropDown.Editable = status;
            app.addnoiseLabel.Enable = status;
        end
        
        function btn_reset_save (app,status)
            app.resetButton.Enable = status;
            app.saveButton.Enable = status;
        end
        
        function result = gray_image(app,img)
            grayImg = rgb2gray(img);
            GrayImg3 = cat(3, grayImg, grayImg, grayImg);
            result = uint8(GrayImg3);
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: UploadImage
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, 'Uplaod image');
            if isequal(file, 0)
                return;
            end
            app.AddressimageEditField.Value = path ;
            image = imread(fullfile(path,file));
            [imageHeight, imageWidth, numChannels] = size(image);
            app.SelectedImage = image ;
            app.SelectedImageOriginal = image;
            app.WidthOriginalValue.Text = num2str(imageWidth);
            app.HeightOriginalValue.Text = num2str(imageHeight);
            app.WidthOutValue.Text = num2str(imageWidth);
            app.HeightOutValue.Text = num2str(imageHeight);
            app.Originalimage.ImageSource = image ;
            app.Outputimage.ImageSource = image ;
            enable_disabel_all(app,"on")
        end

        % Button pushed function: resizeButton
        function resizeButtonPushed(app, event)
            btn_reset_save(app,"on");
            x =  app.xResize.Value ;
            y =  app.yResize;
            if x <= 10 || y <=10
                errordlg('X and Y must be greater than 10', 'Error');
                return;
            end
            resized_img = imresize(app.Outputimage.ImageSource, 0.1);
            app.SelectedImage = resized_img;
            [imageHeight, imageWidth, numChannels] = size(resized_img);
            app.WidthOutValue.Text = num2str(imageWidth);
            app.HeightOutValue.Text = num2str(imageHeight);
            app.Outputimage.ImageSource = resized_img;
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            btn_reset_save(app,"off")
            choice = uiconfirm(app.imageprocessingUIFigure, 'Do you want to reset image?','Confirmation','Options', {'Yes', 'Cancel'},'DefaultOption', 2);
            if strcmp(choice,'Yes')
                image_originial = app.SelectedImageOriginal;
                app.SelectedImage = image_originial;
                app.Outputimage.ImageSource = image_originial;
                [imageHeight, imageWidth, numChannels] = size(image_originial);
                app.WidthOutValue.Text = num2str(imageWidth);
                app.HeightOutValue.Text = num2str(imageHeight);
                app.brightnessSlider.Value = 0;
                app.addnoiseDropDown.Value = '------';
                app.graySwitch.Value = 'off';
            else
                return;
            end
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            % pass
        end

        % Value changing function: brightnessSlider
        function brightnessSliderValueChanging(app, event)
            changingValue = event.Value;
            btn_reset_save(app,"on");
            brightenedImage = uint8(double(app.SelectedImage) * changingValue);
            app.Outputimage.ImageSource = brightenedImage;
        end

        % Value changed function: graySwitch
        function graySwitchValueChanged(app, event)
            value = app.graySwitch.Value;
            btn_reset_save(app,"on");
            if strcmp(value,'on')
                out_image = app.Outputimage.ImageSource;
                app.beforegray = out_image;
                app.beforegray2 = app.SelectedImage;
                if ndims(out_image) ~= 3 || size(out_image, 3) ~= 3
                    errordlg('The image is not in RGB format.', 'Error');
                else
                    app.Outputimage.ImageSource = gray_image(app,out_image);
                    app.SelectedImage = gray_image(app,out_image);
                end
            else
                app.Outputimage.ImageSource = app.beforegray;
                app.SelectedImage = app.beforegray2;
            end
            
        end

        % Value changed function: addnoiseDropDown
        function addnoiseDropDownValueChanged(app, event)
            value = app.addnoiseDropDown.Value;
            btn_reset_save(app,"on");
            if strcmp(value,'salt & pepper')
                prompt = {'d:'};
                defaultAnswer = {'0.2'};
            elseif strcmp(value,'gaussian')
                prompt = {'M:', 'var_gauss:'};
                defaultAnswer = {'0.3', '0.3'};
            else
                return;
            end
            dlgTitle = 'Input';
            numLines = 1;
            
            userInputs = inputdlg(prompt, dlgTitle, numLines, defaultAnswer);
            if isempty(userInputs)
                return;
            end
            value1 = str2double(userInputs{1});
            im = app.Outputimage.ImageSource;
            if strcmp(value,'salt & pepper')
                
                img=imnoise(im,'salt & pepper',value1);
            else
                value2 = str2double(userInputs{2});
                img=imnoise(im,'gaussian',value1,value2);
            end
            app.Outputimage.ImageSource = img;
            app.SelectedImage = img;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.imageprocessingUIFigure = uifigure('Visible', 'off');
            app.imageprocessingUIFigure.Name = 'image processing';
            app.TabGroup = uitabgroup(app.imageprocessingUIFigure);
            app.OutputimageTab = uitab(app.TabGroup);
            app.OutputimageTab.Title = 'Output image';
            app.Outputimage = uiimage(app.OutputimageTab);
            app.WidthOutLabel = uilabel(app.OutputimageTab);
            app.WidthOutLabel.Text = 'Width : ';
            app.WidthOutValue = uilabel(app.OutputimageTab);
            app.WidthOutValue.Text = '0';
            app.HeightOutLabel = uilabel(app.OutputimageTab);
            app.HeightOutLabel.Text = 'Height : ';
            app.HeightOutValue = uilabel(app.OutputimageTab);
            app.HeightOutValue.Text = '0';
            app.OriginalimageTab = uitab(app.TabGroup);
            app.OriginalimageTab.Title = 'Original image';
            app.Originalimage = uiimage(app.OriginalimageTab);
            app.WidthOriginalLabel = uilabel(app.OriginalimageTab);
            app.WidthOriginalLabel.Text = 'Width : ';
            app.WidthOriginalValue = uilabel(app.OriginalimageTab);
            app.WidthOriginalValue.Text = '0';
            app.HeightOriginalLabel = uilabel(app.OriginalimageTab);
            app.HeightOriginalLabel.Text = 'Height : ';
            app.HeightOriginalValue = uilabel(app.OriginalimageTab);
            app.HeightOriginalValue.Text = '0';
            app.Panel = uipanel(app.imageprocessingUIFigure);
            app.UploadimageLabel = uilabel(app.Panel);
            app.UploadimageLabel.Text = 'Upload image';
            app.addressimageLabel = uilabel(app.Panel);
            app.addressimageLabel.Text = 'Address image :';
            app.AddressimageEditField = uieditfield(app.Panel, 'text');
            app.UploadImage = uibutton(app.Panel, 'push');
            app.UploadImage.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImage.Icon = fullfile(pathToMLAPP, 'upload.png');
            app.UploadImage.Text = '';
            app.Panel_2 = uipanel(app.imageprocessingUIFigure);
            app.brightnessLabel = uilabel(app.Panel_2);
            app.brightnessLabel.Enable = 'off';
            app.brightnessLabel.Text = 'brightness : ';
            app.brightnessSlider = uislider(app.Panel_2);
            app.brightnessSlider.Limits = [0 10];
            app.brightnessSlider.ValueChangingFcn = createCallbackFcn(app, @brightnessSliderValueChanging, true);
            app.brightnessSlider.Enable = 'off';
            app.graySwitchLabel = uilabel(app.Panel_2);
            app.graySwitchLabel.Enable = 'off';
            app.graySwitchLabel.Text = 'gray :';
            app.graySwitch = uiswitch(app.Panel_2, 'slider');
            app.graySwitch.Items = {'off', 'on'};
            app.graySwitch.ValueChangedFcn = createCallbackFcn(app, @graySwitchValueChanged, true);
            app.graySwitch.Enable = 'off';
            app.graySwitch.Value = 'off';
            app.Switch_2Label = uilabel(app.Panel_2);
            app.Switch_2Label.Text = 'Switch';
            app.Switch_2 = uiswitch(app.Panel_2, 'slider');
            app.Switch_2.Items = {'off', 'on'};
            app.Switch_2.Value = 'off';
            app.WidthLabel = uilabel(app.Panel_2);
            app.WidthLabel.Enable = 'off';
            app.WidthLabel.Text = 'Width :';
            app.xResize = uieditfield(app.Panel_2, 'numeric');
            app.xResize.ValueDisplayFormat = '%.0f';
            app.xResize.Enable = 'off';
            app.WidthLabel_2 = uilabel(app.Panel_2);
            app.WidthLabel_2.Enable = 'off';
            app.WidthLabel_2.Text = 'Height :';
            app.yResize = uieditfield(app.Panel_2, 'numeric');
            app.yResize.ValueDisplayFormat = '%.0f';
            app.yResize.Editable = 'off';
            app.yResize.Enable = 'off';
            app.ResizeLabel = uilabel(app.Panel_2);
            app.ResizeLabel.Text = 'Resize';
            app.ImageprocessingLabel = uilabel(app.Panel_2);
            app.ImageprocessingLabel.Text = 'Image processing';
            app.resizeButton = uibutton(app.Panel_2, 'push');
            app.resizeButton.ButtonPushedFcn = createCallbackFcn(app, @resizeButtonPushed, true);
            app.resizeButton.Enable = 'off';
            app.resizeButton.Text = 'resize';
            app.resetButton = uibutton(app.Panel_2, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Icon = 'slrtRebootIcon.png';
            app.resetButton.Enable = 'off';
            app.resetButton.Text = 'reset';
            app.saveButton = uibutton(app.Panel_2, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Icon = 'saveResults_24.png';
            app.saveButton.Enable = 'off';
            app.saveButton.Text = 'save';
            app.addnoiseLabel = uilabel(app.Panel_2);
            app.addnoiseLabel.Enable = 'off';
            app.addnoiseLabel.Text = 'add noise :';
            app.addnoiseDropDown = uidropdown(app.Panel_2);
            app.addnoiseDropDown.Items = {'------', 'salt & pepper', 'gaussian'};
            app.addnoiseDropDown.ValueChangedFcn = createCallbackFcn(app, @addnoiseDropDownValueChanged, true);
            app.addnoiseDropDown.Enable = 'off';
            app.addnoiseDropDown.Value = '------';
            % Show the figure after all components are created
            app.imageprocessingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = index

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.imageprocessingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.imageprocessingUIFigure)
        end
    end
end